classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        STATISTICALANALYSISOFTEXTFILESLabel  matlab.ui.control.Label
        EnterNamePathofTextfileEditFieldLabel  matlab.ui.control.Label
        EnterNamePathofTextfileEditField  matlab.ui.control.EditField
        StartButton                 matlab.ui.control.Button
        MostRepeatet5LettersButton  matlab.ui.control.Button
        PMFButton                   matlab.ui.control.Button
        CDFButton                   matlab.ui.control.Button
        MeanButton                  matlab.ui.control.Button
        PlotLabel                   matlab.ui.control.Label
        ShowLabel                   matlab.ui.control.Label
        VarianceButton              matlab.ui.control.Button
        SkewnessButton              matlab.ui.control.Button
        KurtosisButton              matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function LetterCount = count(filename,A)
            fid = fopen(filename,'rt');
            if fid < 0
                LetterCount = -1;
                return
            end
            if fid >0 && ischar(A)
                k=0;
                oneread = fgets(fid);
                while ischar(oneread)
                    k = k + count(oneread,A)+count(oneread,upper(A));
                    oneread = fgets(fid);
                end
                LetterCount = k;
            else
                LetterCount = -1;
            end
            fclose(fid);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            AZ='a':'z';
            n=1:26;
            for k=1:26
                num(k,1)=LetterCount(app.EnterNamePathofTextfileEditField.Value,AZ(k));
            end
            total=sum(num);
            freq=100*num./total;
            aspacez={'a   ','b   ','c   ','d   ','e   ','f   ','g   ','h   ','i   ','j   ','k   ','l   ','m   ','n   ','o   ','p   ','q   ','r   ','s   ','t   ','u   ','v   ','w   ','x   ','y   ','z   '};
            aspacez=transpose(aspacez);
            X = cellstr(aspacez);
            X = categorical(X);
            bar(app.UIAxes,X,freq);
            title(app.UIAxes,'Probability in Percentage of Each Letter');
            
        end

        % Value changed function: EnterNamePathofTextfileEditField
        function EnterNamePathofTextfileEditFieldValueChanged(app, event)
            value = app.EnterNamePathofTextfileEditField.Value;
        end

        % Button pushed function: MostRepeatet5LettersButton
        function MostRepeatet5LettersButtonPushed(app, event)
            AZ='a':'z';
            n=1:26;
            for k=1:26
                num(k,1)=LetterCount(app.EnterNamePathofTextfileEditField.Value,AZ(k));
            end
            total=sum(num);
            freq=100*num./total;
            [Y, maxfreq]= max5(freq);
            bar(app.UIAxes,Y,maxfreq);
            title(app.UIAxes,'Probability in Percentage of the Most Repeated 5 Letters');
        end

        % Button pushed function: PMFButton
        function PMFButtonPushed(app, event)
            n=1:26;
            AZ='a':'z';
            for k=1:26
                num(k,1)=LetterCount(app.EnterNamePathofTextfileEditField.Value,AZ(k));
            end
            total=sum(num);
            freq=100*num./total;
            bar(app.UIAxes,n,freq);
            title(app.UIAxes,'PDF of letters');
        end

        % Button pushed function: CDFButton
        function CDFButtonPushed(app, event)
            n=1:26;
            AZ='a':'z';
            for k=1:26
                num(k,1)=LetterCount(app.EnterNamePathofTextfileEditField.Value,AZ(k));
            end
            total=sum(num);
            freq=100*num./total;
            
            cumulative(1)=freq(1);
            for i=2:length(freq)
                cumulative(i)=cumulative(i-1)+freq(i);
            end
            %%disp(cumulative);
            bar(app.UIAxes,n,cumulative);
            title(app.UIAxes,'CDF of letters');
        end

        % Button pushed function: MeanButton
        function MeanButtonPushed(app, event)
            n=1:26;
            AZ='a':'z';
            for k=1:26
                num(k,1)=LetterCount(app.EnterNamePathofTextfileEditField.Value,AZ(k));
            end
            total=sum(num);
            freq=100*num./total;
            mean=0;
            
            for i=2:26
                mean=mean+freq(i)*n(i);
            end
            mean=mean/100;
            mean=string(mean);
            f = msgbox(mean,'Mean','');


        end

        % Button pushed function: VarianceButton
        function VarianceButtonPushed(app, event)
            n=1:26;
            AZ='a':'z';
            for k=1:26
                num(k,1)=LetterCount(app.EnterNamePathofTextfileEditField.Value,AZ(k));
            end
            total=sum(num);
            freq=100*num./total;
            mean=0;
            expx2=0;
            for i=2:26
                mean=mean+freq(i)*n(i);
                expx2=expx2+ (n(i)^(2)*freq(i));
            end
            mean=mean/100;
            
            var=expx2-(mean*mean);
            var=var/100;
            var=string(var);
            f = msgbox(var,'Variance','');
            
        end

        % Button pushed function: SkewnessButton
        function SkewnessButtonPushed(app, event)
            n=1:26;
            AZ='a':'z';
            for k=1:26
                num(k,1)=LetterCount(app.EnterNamePathofTextfileEditField.Value,AZ(k));
            end
            total=sum(num);
            freq=100*num./total;
            mean=0;
            expx2=0;
            expx3=0;
            for i=2:26
                mean=mean+freq(i)*n(i);
                expx2=expx2+ (n(i)^(2)*freq(i));
                expx3=expx3+ (n(i)^(3)*freq(i));
            end
            mean=mean/100;
            var=expx2-(mean*mean);
            var=var/100;
            skew=(expx3-3*mean*var-mean^(3))/var^(3/2);
            skew=string(skew);
            f = msgbox(skew,'Skewness','');
            
        end

        % Button pushed function: KurtosisButton
        function KurtosisButtonPushed(app, event)
            n=1:26;
            AZ='a':'z';
            for k=1:26
                num(k,1)=LetterCount(app.EnterNamePathofTextfileEditField.Value,AZ(k));
            end
            total=sum(num);
            freq=100*num./total;
            mean=0;
            expx2=0;
            for i=2:26
                mean=mean+freq(i)*n(i);
                expx2=expx2+ (n(i)^(2)*freq(i));
            end
            mean=mean/100;
            var=expx2-(mean*mean);
            var=var/100;
            kurt=0;
            for i=1:26
                kurt=kurt+((n(i)-mean)/var^(1/2)).^(4)*freq(i);
            end
            kurt=string(kurt);
            f = msgbox(kurt,'Kurtosis','');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.STATISTICALANALYSISOFTEXTFILESLabel = uilabel(app.UIFigure);
            app.STATISTICALANALYSISOFTEXTFILESLabel.Text = 'STATISTICAL ANALYSIS OF TEXT FILES';
            app.EnterNamePathofTextfileEditFieldLabel = uilabel(app.UIFigure);
            app.EnterNamePathofTextfileEditFieldLabel.Text = 'Enter Name/ Path of Text file';
            app.EnterNamePathofTextfileEditField = uieditfield(app.UIFigure, 'text');
            app.EnterNamePathofTextfileEditField.ValueChangedFcn = createCallbackFcn(app, @EnterNamePathofTextfileEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MostRepeatet5LettersButton = uibutton(app.UIFigure, 'push');
            app.MostRepeatet5LettersButton.ButtonPushedFcn = createCallbackFcn(app, @MostRepeatet5LettersButtonPushed, true);
            app.MostRepeatet5LettersButton.Text = 'Most Repeatet 5 Letters';
            app.PMFButton = uibutton(app.UIFigure, 'push');
            app.PMFButton.ButtonPushedFcn = createCallbackFcn(app, @PMFButtonPushed, true);
            app.PMFButton.Text = 'PMF';
            app.CDFButton = uibutton(app.UIFigure, 'push');
            app.CDFButton.ButtonPushedFcn = createCallbackFcn(app, @CDFButtonPushed, true);
            app.CDFButton.Text = 'CDF';
            app.MeanButton = uibutton(app.UIFigure, 'push');
            app.MeanButton.ButtonPushedFcn = createCallbackFcn(app, @MeanButtonPushed, true);
            app.MeanButton.Text = 'Mean';
            app.PlotLabel = uilabel(app.UIFigure);
            app.PlotLabel.Text = 'Plot';
            app.ShowLabel = uilabel(app.UIFigure);
            app.ShowLabel.Text = 'Show';
            app.VarianceButton = uibutton(app.UIFigure, 'push');
            app.VarianceButton.ButtonPushedFcn = createCallbackFcn(app, @VarianceButtonPushed, true);
            app.VarianceButton.Text = 'Variance';
            app.SkewnessButton = uibutton(app.UIFigure, 'push');
            app.SkewnessButton.ButtonPushedFcn = createCallbackFcn(app, @SkewnessButtonPushed, true);
            app.SkewnessButton.Text = 'Skewness';
            app.KurtosisButton = uibutton(app.UIFigure, 'push');
            app.KurtosisButton.ButtonPushedFcn = createCallbackFcn(app, @KurtosisButtonPushed, true);
            app.KurtosisButton.Text = 'Kurtosis';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end